classdef Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControllerCompareUIFigure  matlab.ui.Figure
        ColorMapDropDownLabel      matlab.ui.control.Label
        ColorMapDropDown           matlab.ui.control.DropDown
        ActionButtonGroup          matlab.ui.container.ButtonGroup
        AdjustContrastButton       matlab.ui.control.RadioButton
        SelectROIButton            matlab.ui.control.RadioButton
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = public)
        sbefore; % Description
        safter;
        before;
        after;
        Ftrace;
        traceX;
        traceY;
        recA;
        recB;
        listerA;
        listerB;
        roiPosition;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sbefore, safter, Ftrace, before, after)
            app.sbefore = sbefore;
            app.safter = safter;
            app.before = before;
            app.after = after;
            app.Ftrace = Ftrace;
                        
            % default the full roi
            roiPosition = [1 1 size(app.before,2) size(app.before,1)];
            hAx = getAxesHandle(app.sbefore);
            axes(hAx);
            app.recA = rectangle('Position', roiPosition, 'EdgeColor','red');
            hBx = getAxesHandle(app.safter);
            axes(hBx);
            app.recB = rectangle('Position', roiPosition, 'EdgeColor','red');
            [traceX, traceY] =getTraces(before, after, []);
            app.traceX = traceX;
            app.traceY = traceY;
            
            app.listerA =  addlistener(app.sbefore,'SliderValueChanging',@(src,evt)changeslice(src,evt,app.safter,app.Ftrace,app.traceX,app.traceY));
            app.listerB = addlistener(app.safter,'SliderValueChanging',@(src,evt)changeslice(src,evt,app.sbefore,app.Ftrace,app.traceX,app.traceY));
            
        end

        % Callback function
        function AdjustContrastButtonPushed(app, event)
            set(app.sbefore,'DisplayRangeInteraction','on');
            set(app.safter,'DisplayRangeInteraction','on');
            app.SelectROIButton.Enable = false;
        end

        % Selection changed function: ActionButtonGroup
        function ActionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ActionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Adjust Contrast'
                    set(app.sbefore,'DisplayRangeInteraction','on');
                    set(app.safter,'DisplayRangeInteraction','on');
                    
                    %                     app.listerA =  addlistener(app.sbefore,'SliderValueChanging',@(src,evt)changeslice(src,evt,app.safter,app.Ftrace,app.traceX,app.traceY));
                    %                     app.listerB = addlistener(app.safter,'SliderValueChanging',@(src,evt)changeslice(src,evt,app.sbefore,app.Ftrace,app.traceX,app.traceY));
                    %
                    
                case 'Select ROI'
                    delete(app.recA);
                    delete(app.recB);
                    
                    
                    set(app.sbefore,'DisplayRangeInteraction','off');
                    set(app.safter,'DisplayRangeInteraction','off');
                    
                    hBox = imrect;
                    app.roiPosition = wait(hBox);
                    app.Label.Text = mat2str(floor(app.roiPosition));
                    
                    delete(hBox);
                    current = app.sbefore.SliceNumber;
                    [traceX, traceY] =getTraces(app.before, app.after, app.roiPosition);
                    
                    app.traceX = traceX;
                    app.traceY = traceY;
                    
                    drawTrace(app.Ftrace,traceX, traceY,current);
                    
                    delete(app.listerA);
                    delete(app.listerB);
                    
                    app.listerA = addlistener(app.sbefore,'SliderValueChanging',@(src,evt)changeslice(src,evt,app.safter,app.Ftrace,app.traceX,app.traceY));
                    app.listerB = addlistener(app.safter,'SliderValueChanging',@(src,evt)changeslice(src,evt,app.sbefore,app.Ftrace,app.traceX,app.traceY));
                    
                    
                    hAx = getAxesHandle(app.sbefore);
                    axes(hAx);
                    app.recA = rectangle('Position', app.roiPosition, 'EdgeColor','red');
                    hBx = getAxesHandle(app.safter);
                    axes(hBx);
                    app.recB = rectangle('Position', app.roiPosition, 'EdgeColor','red');
                    
                    app.AdjustContrastButton.Value=1;
                    set(app.sbefore,'DisplayRangeInteraction','on');
                    set(app.safter,'DisplayRangeInteraction','on');
                    
                    
            end
        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
            value = app.ColorMapDropDown.Value;
            app.sbefore.Colormap=colormap(value);
            app.safter.Colormap=colormap(value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControllerCompareUIFigure = uifigure('Visible', 'off');
            app.ControllerCompareUIFigure.Name = 'Controller - Compare';
            app.ColorMapDropDownLabel = uilabel(app.ControllerCompareUIFigure);
            app.ColorMapDropDownLabel.Text = 'Color Map';
            app.ColorMapDropDown = uidropdown(app.ControllerCompareUIFigure);
            app.ColorMapDropDown.Items = {'gray', 'parula', 'jet', 'hsv', 'hot', 'winter'};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Value = 'gray';
            app.ActionButtonGroup = uibuttongroup(app.ControllerCompareUIFigure);
            app.ActionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ActionButtonGroupSelectionChanged, true);
            app.ActionButtonGroup.Title = 'Action';
            app.AdjustContrastButton = uiradiobutton(app.ActionButtonGroup);
            app.AdjustContrastButton.Text = 'Adjust Contrast';
            app.AdjustContrastButton.Value = true;
            app.SelectROIButton = uiradiobutton(app.ActionButtonGroup);
            app.SelectROIButton.Text = 'Select ROI';
            app.Label = uilabel(app.ControllerCompareUIFigure);
            app.Label.Tag = 'roipos';
            app.Label.Text = '';
            % Show the figure after all components are created
            app.ControllerCompareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controller(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControllerCompareUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControllerCompareUIFigure)
        end
    end
end